classdef CrystalPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CrystalPlotUIFigure  matlab.ui.Figure
        FileMenu             matlab.ui.container.Menu
        OpenstructureMenu    matlab.ui.container.Menu
        SavestructureMenu    matlab.ui.container.Menu
        SaveBZMenu           matlab.ui.container.Menu
        ExitMenu             matlab.ui.container.Menu
        EditMenu             matlab.ui.container.Menu
        ViewMenu             matlab.ui.container.Menu
        ObjectsMenu          matlab.ui.container.Menu
        UtilitiesMenu        matlab.ui.container.Menu
        HelpMenu             matlab.ui.container.Menu
        Toolbar              matlab.ui.container.Toolbar
        PushTool             matlab.ui.container.toolbar.PushTool
        GridLayout           matlab.ui.container.GridLayout
        TabGroup3            matlab.ui.container.TabGroup
        ToolsTab             matlab.ui.container.Tab
        GridLayout10         matlab.ui.container.GridLayout
        LightingPanel        matlab.ui.container.Panel
        GridLayout13         matlab.ui.container.GridLayout
        UITable              matlab.ui.control.Table
        AppearancePanel      matlab.ui.container.Panel
        GridLayout11         matlab.ui.container.GridLayout
        GridLayout14         matlab.ui.container.GridLayout
        EditField_1          matlab.ui.control.NumericEditField
        EditField_2          matlab.ui.control.NumericEditField
        ViewLabel            matlab.ui.control.Label
        OptionsLabel         matlab.ui.control.Label
        GridLayout12         matlab.ui.container.GridLayout
        DropDown             matlab.ui.control.DropDown
        EditField_3          matlab.ui.control.NumericEditField
        BondsCheckBox        matlab.ui.control.CheckBox
        UnitcellCheckBox     matlab.ui.control.CheckBox
        QualitySliderLabel   matlab.ui.control.Label
        QualitySlider        matlab.ui.control.Slider
        StyleTab_2           matlab.ui.container.Tab
        ObjectsTab           matlab.ui.container.Tab
        GridLayout15         matlab.ui.container.GridLayout
        UITable2             matlab.ui.control.Table
        Panel                matlab.ui.container.Panel
        GridLayout9          matlab.ui.container.GridLayout
        Button               matlab.ui.control.Button
        GridLayout2          matlab.ui.container.GridLayout
        TabGroup2            matlab.ui.container.TabGroup
        OutputTab            matlab.ui.container.Tab
        GridLayout4          matlab.ui.container.GridLayout
        TextArea_output      matlab.ui.control.TextArea
        SummaryTab           matlab.ui.container.Tab
        GridLayout5          matlab.ui.container.GridLayout
        TextArea             matlab.ui.control.TextArea
        CommentTab           matlab.ui.container.Tab
        GridLayout6          matlab.ui.container.GridLayout
        TextArea_3           matlab.ui.control.TextArea
        TabGroup             matlab.ui.container.TabGroup
        StructureTab         matlab.ui.container.Tab
        GridLayout3          matlab.ui.container.GridLayout
        UIAxes               matlab.ui.control.UIAxes
        BrillouinZoneTab     matlab.ui.container.Tab
        GridLayout7          matlab.ui.container.GridLayout
        UIAxes_2             matlab.ui.control.UIAxes
        ContextMenu          matlab.ui.container.ContextMenu
        AddMenu              matlab.ui.container.Menu
        RemoveMenu           matlab.ui.container.Menu
    end


    properties (Access = private)
        posdata % POSCAR data
        pospath % POSCAR path
        fiopen = false; % file opened or not
        separate_str = '====================================================================================';
        element_id = [1,1:96];
        element_name = {'H', 'D', 'He', 'Li', 'Be', 'B', 'C', 'N', 'O',	'F',...
            'Ne', 'Na',	'Mg', 'Al',	'Si', 'P', 'S', 'Cl', 'Ar',	'K', 'Ca', 'Sc', ...
            'Ti', 'V', 'Cr', 'Mn', 'Fe', 'Co', 'Ni', 'Cu', 'Zn', 'Ga', 'Ge', 'As', ...
            'Se', 'Br', 'Kr', 'Rb',	'Sr', 'Y', 'Zr', 'Nb', 'Mo', 'Tc', 'Ru', 'Rh', 'Pd',...
            'Ag', 'Cd', 'In', 'Sn',	'Sb', 'Te',	'I', 'Xe', 'Cs', 'Ba', 'La', 'Ce', 'Pr', 'Nd',...
            'Pm', 'Sm', 'Eu', 'Gd', 'Tb', 'Dy', 'Ho', 'Er',	'Tm', 'Yb', 'Lu', 'Hf',	'Ta', 'W',...
            'Re', 'Os', 'Ir', 'Pt',	'Au', 'Hg', 'Tl', 'Pb', 'Bi', 'Po',	'At', 'Rn',	'Fr', 'Ra',...
            'Ac', 'Th',	'Pa', 'U',	'Np', 'Pu',	'Am', 'XX'};
        element_radius = [1.2	1.2	1.4	1.4	1.4	1.4	1.7	1.55	1.52	1.47	1.54	1.54	1.54	1.54	2.1	1.8	1.8	1.75	1.88	1.88	1.88	1.88	1.88	1.88	1.88	1.88	1.88	1.88	1.88	1.88	1.88	1.88	1.88	1.85	1.9	1.85	2.02	2.02	2.02	2.02	2.02	2.02	2.02	2.02	2.02	2.02	2.02	2.02	2.02	2.02	2.02	2	2.06	1.98	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	2.16	1 ...
            ]*0.25;
        element_color = [1	0.8	0.8;0.8	0.8	1;0.98907	0.91312	0.81091;0.52731	0.87953	0.4567;...
            0.37147	0.8459	0.48292;0.1249	0.63612	0.05948;0.5043	0.28659	0.16236;0.69139	0.72934	0.9028;...
            0.99997	0.01328	0;0.69139	0.72934	0.9028;0.99954	0.21788	0.71035;0.97955	0.86618	0.23787;...
            0.98773	0.48452	0.0847;0.50718	0.70056	0.84062;0.10596	0.23226	0.98096;0.75557	0.61256	0.76425;...
            1	0.98071	0;0.19583	0.98828	0.01167;0.81349	0.99731	0.77075;0.63255	0.13281	0.96858;...
            0.35642	0.58863	0.74498;0.71209	0.3893	0.67279;0.47237	0.79393	1;0.9	0.1	0;0	0	0.62;...
            0.66148	0.03412	0.62036;0.71051	0.44662	0.00136;0	0	0.68666;0.72032	0.73631	0.74339;...
            0.1339	0.28022	0.86606;0.56123	0.56445	0.50799;0.62292	0.89293	0.45486;0.49557	0.43499	0.65193;...
            0.45814	0.81694	0.34249;0.6042	0.93874	0.06122;0.49645	0.19333	0.01076;0.98102	0.75805	0.95413;...
            1	0	0.6;0	1	0.15259;0.40259	0.59739	0.55813;0	1	0;0.29992	0.70007	0.46459;0.70584	0.52602	0.68925;...
            0.80574	0.68699	0.79478;0.81184	0.72113	0.68089;0.80748	0.82205	0.67068;0.75978	0.76818	0.72454;...
            0.72032	0.73631	0.74339;0.95145	0.12102	0.86354;0.84378	0.50401	0.73483;0.60764	0.56052	0.72926;...
            0.84627	0.51498	0.31315;0.67958	0.63586	0.32038;0.55914	0.122	0.54453;0.60662	0.63218	0.97305;...
            0.05872	0.99922	0.72578;0.11835	0.93959	0.17565;0.3534	0.77057	0.28737;0.82055	0.99071	0.02374;...
            0.9913	0.88559	0.02315;0.98701	0.5556	0.02744;0	0	0.96;0.99042	0.02403	0.49195;0.98367	0.03078	0.83615;...
            0.75325	0.01445	1;0.44315	0.01663	0.99782;0.1939	0.02374	0.99071;0.02837	0.25876	0.98608;...
            0.28688	0.45071	0.23043;0	0	0.88;0.15323	0.99165	0.95836;0.15097	0.99391	0.71032;...
            0.70704	0.70552	0.3509;0.71952	0.60694	0.33841;0.55616	0.54257	0.50178;0.70294	0.69401	0.55789;...
            0.78703	0.69512	0.47379;0.78975	0.81033	0.45049;0.79997	0.77511	0.75068;0.99628	0.70149	0.22106;...
            0.8294	0.72125	0.79823;0.58798	0.53854	0.42649;0.32386	0.32592	0.35729;0.82428	0.18732	0.97211;...
            0	0	1;0	0	1;1	1	0;0	0	0;0.42959	0.66659	0.34786;0.39344	0.62101	0.45034;0.14893	0.99596	0.47106;...
            0.16101	0.98387	0.20855;0.47774	0.63362	0.66714;0.3	0.3	0.3;0.3	0.3	0.3;0.3	0.3	0.3;0.3	0.3	0.3];
        UITableLighting_cellid % position of Lighting cell
        atom_info = cell(1,1); % Atom info
        UITableAtom_cellid % Description
    end

    methods (Access = private)

        function print_poscar_info(app)
            % print poscar data
            app.TextArea_output.Value = append_str(app,app.TextArea_output.Value,app.separate_str);
            app.TextArea_output.Value = append_str(app,app.TextArea_output.Value,app.pospath);
            app.TextArea_output.Value = append_str(app,app.TextArea_output.Value,app.separate_str);
            app.TextArea_output.Value = append_str(app,app.TextArea_output.Value,'Title');
            app.TextArea_output.Value = append_str(app,app.TextArea_output.Value,app.posdata.comment);
            app.TextArea_output.Value = append_str(app,app.TextArea_output.Value,'');
            app.TextArea_output.Value = append_str(app,app.TextArea_output.Value,'Lattice vectors');
            app.TextArea_output.Value = append_str(app,app.TextArea_output.Value,sprintf('%15.8f %15.8f %15.8f',app.posdata.lattice(1,:)));
            app.TextArea_output.Value = append_str(app,app.TextArea_output.Value,sprintf('%15.8f %15.8f %15.8f',app.posdata.lattice(2,:)));
            app.TextArea_output.Value = append_str(app,app.TextArea_output.Value,sprintf('%15.8f %15.8f %15.8f',app.posdata.lattice(3,:)));
            app.TextArea_output.Value = append_str(app,app.TextArea_output.Value,'');
            app.TextArea_output.Value = append_str(app,app.TextArea_output.Value,'Atomic Info');
            for i = 1:size(app.posdata.symbols,1)
                app.TextArea_output.Value = append_str(app,app.TextArea_output.Value,sprintf('%s %d',app.posdata.symbols{i},app.posdata.atomcount(i)));
            end
            app.TextArea_output.Value = append_str(app,app.TextArea_output.Value,'');
            app.TextArea_output.Value = append_str(app,app.TextArea_output.Value,'Atom position');
            cnt = 0;
            for i = 1:size(app.posdata.symbols,1)
                for j = 1:app.posdata.atomcount(i)
                    cnt = cnt + 1;
                    app.TextArea_output.Value = append_str(app,app.TextArea_output.Value,sprintf('%s %15.8f %15.8f %15.8f',app.posdata.symbols{i},app.posdata.coords(cnt,:)));
                end
            end
            app.TextArea_output.Value = append_str(app,app.TextArea_output.Value,'');
            app.TextArea_output.Value = append_str(app,app.TextArea_output.Value,app.separate_str);
            app.TextArea_output.Value = append_str(app,app.TextArea_output.Value,'');
        end

        function result = append_str(~,str1,str2)
            if strcmp(str1,'')
                result = str2;
            else
                result = [str1(:)', {str2}];
            end
        end


        function plot_BZ(app)
            % view-source:http://lampx.tugraz.at/~hadley/ss1/bzones/drawing_BZ.php

            lat_vec = app.posdata.lattice;
            % define variables

            G = zeros(1,4); % the reciprocal lattice vectors, the fourth index checks
            % if it is in the 1 Bz

            G1 = zeros(1,3); % the reciprocal lattice vectors used to draw the 1st Bz

            % hkl plane
            hkl = [[0,0,1];[0,0,-1];[0,1,0];[0,-1,0];[0,1,1];[0,-1,-1];[1,0,0];...
                [-1,0,0];[1,0,1];[-1,0,-1];[1,1,0];[-1,-1,0];[1,1,1];[-1,-1,-1];...
                [1,1,-1];[-1,-1,1];[1,-1,1];[-1,1,-1];[-1,1,1];[1,-1,-1];[-1,1,0];...
                [1,-1,0];[1,0,-1];[-1,0,1];[0,1,-1];[0,-1,1]];


            corners = zeros(1,4); %the first elements are the coordinates,
            %the next three are the planes that intersect
            %at that point, the last is the Bz index.

            cornersbz = zeros(1,3); % the corners of the Bz zone

            edges = zeros(1,6); % the edges of the Bz zone

            % lattice vectors
            a1x = lat_vec(1,1); a1y = lat_vec(1,2); a1z = lat_vec(1,3);
            a2x = lat_vec(2,1); a2y = lat_vec(2,2); a2z = lat_vec(2,3);
            a3x = lat_vec(3,1); a3y = lat_vec(3,2); a3z = lat_vec(3,3);

            Np = 0; % number of planes

            Nc = 0; % number of corners

            Ne = 0; % number of edges

            % volume
            v = a1x*(a2y*a3z-a2z*a3y)+a1y*(a2z*a3x-a2x*a3z)+a1z*(a2x*a3y-a2y*a3x);

            % reciprocal lattice vectors
            b1x = 2*pi*(a2y*a3z-a2z*a3y)/v;
            b1y = 2*pi*(a2z*a3x-a2x*a3z)/v;
            b1z = 2*pi*(a2x*a3y-a2y*a3x)/v;
            b2x = 2*pi*(a3y*a1z-a3z*a1y)/v;
            b2y = 2*pi*(a3z*a1x-a3x*a1z)/v;
            b2z = 2*pi*(a3x*a1y-a3y*a1x)/v;
            b3x = 2*pi*(a1y*a2z-a1z*a2y)/v;
            b3y = 2*pi*(a1z*a2x-a1x*a2z)/v;
            b3z = 2*pi*(a1x*a2y-a1y*a2x)/v;

            % define the reciprocal lattice vectors
            for i = 1:size(hkl,1)
                G(i,:) = [hkl(i,1)*b1x+hkl(i,2)*b2x+hkl(i,3)*b3x,...
                    hkl(i,1)*b1y+hkl(i,2)*b2y+hkl(i,3)*b3y,...
                    hkl(i,1)*b1z+hkl(i,2)*b2z+hkl(i,3)*b3z,1];
            end

            % find the planes that form the boundaries of the first Bz
            for i = 1:size(hkl,1)
                Dgamma = sqrt((0.5*G(i,1))^2 + (0.5*G(i,2))^2 + (0.5*G(i,3))^2);
                for j = 1:size(hkl,1)/2
                    if (j~=i)
                        GG = sqrt((G(i,1)/2-G(j,1))^2+(G(i,2)/2-G(j,2))^2+...
                            (G(i,3)/2-G(j,3))^2);
                        if (GG <= Dgamma)
                            G(i,4) = 0; % this G is not part of the Bz boundary
                            break
                        end
                    end
                end
            end

            fprintf('The first Brillouin zone boundary consists:\n');

            fprintf('Planes:\n');
            G_hkl = zeros(1,3);
            for i = 1:size(hkl,1)
                if (G(i,4) == 1)
                    Np = Np + 1;
                    G1(Np,:) = [G(i,1),G(i,2),G(i,3)];
                    fprintf('(%d,%d,%d)\n',hkl(i,1),hkl(i,2),hkl(i,3));
                    G_hkl(Np,:) = [hkl(i,1),hkl(i,2),hkl(i,3)];
                end
            end
            fprintf('Number of planes: %d\n',Np);

            % This section considers all distinct combinations of three planes
            % and determines the corners where they intersect
            n = 0;
            for i = 3:Np
                for j = 2:i-1
                    for k = 1:j-1
                        A = [[G1(i,1), G1(i,2), G1(i,3)];...
                            [G1(j,1), G1(j,2), G1(j,3)];...
                            [G1(k,1), G1(k,2), G1(k,3)]];
                        b(1) = (G1(i,1)*G1(i,1)+G1(i,2)*G1(i,2)+G1(i,3)*G1(i,3))/2;
                        b(2) = (G1(j,1)*G1(j,1)+G1(j,2)*G1(j,2)+G1(j,3)*G1(j,3))/2;
                        b(3) = (G1(k,1)*G1(k,1)+G1(k,2)*G1(k,2)+G1(k,3)*G1(k,3))/2;
                        if (det(A) ~= 0)
                            n = n+1;
                            %                 fprintf('%f %f %f\n',b);
                            corners(n,:) = [linsolve(A,b')',1];
                        end
                    end
                end
            end

            % find the corners of the Bz by choosing only the corners closer to Gamma
            % than another G
            for i = 1:size(corners,1)
                for j = 1:size(G,1)
                    Dgamma = sqrt(corners(i,1)^2+corners(i,2)^2+corners(i,3)^2);
                    if (j~=i)
                        DGG = sqrt((corners(i,1)-G(j,1))^2+...
                            (corners(i,2)-G(j,2))^2+(corners(i,3)-G(j,3))^2);
                        if DGG < Dgamma
                            corners(i,4) = 0;
                            break
                        end
                    end
                end
            end

            % check to see if the corners are unique
            for i = 2:size(corners,1)
                for j = 1:i-1
                    if (corners(i,1) == corners(j,1)) &&...
                            (corners(i,2) == corners(j,2)) &&...
                            (corners(i,3) == corners(j,3))
                        corners(i,4) = 0;
                    end
                end
            end

            fprintf('Corners:\n');
            dmax = 0;
            for i = 1:size(corners,1)
                if corners(i,4) == 1
                    d = sqrt(corners(i,1)^2+corners(i,2)^2+corners(i,3)^2);
                    dmax = max(dmax,d);
                    Nc = Nc + 1;
                    cornersbz(Nc,:) = [corners(i,1), corners(i,2), corners(i,3)];
                    fprintf('(%.2f,%.2f,%.2f) d=%f\n',cornersbz(Nc,1),...
                        cornersbz(Nc,2),cornersbz(Nc,3), dmax);
                end
            end
            fprintf('Number of corners: %d\n',Nc);

            % for every pair of corners check every pair of planes to see
            % if the corners both lie in those planes
            delta = 1E-20;
            for i = 1:Nc
                for j = 1:i-1
                    for k = 2:Np
                        for l = 1:k-1
                            d_ki = G1(k,1)*cornersbz(i,1)+G1(k,2)*cornersbz(i,2)+...
                                G1(k,3)*cornersbz(i,3)-(G1(k,1)*G1(k,1)+G1(k,2)*G1(k,2)+...
                                G1(k,3)*G1(k,3))/2;
                            d_li = G1(l,1)*cornersbz(i,1)+G1(l,2)*cornersbz(i,2)+...
                                G1(l,3)*cornersbz(i,3)-(G1(l,1)*G1(l,1)+G1(l,2)*G1(l,2)+...
                                G1(l,3)*G1(l,3))/2;
                            d_kj = G1(k,1)*cornersbz(j,1)+G1(k,2)*cornersbz(j,2)+...
                                G1(k,3)*cornersbz(j,3)-(G1(k,1)*G1(k,1)+G1(k,2)*G1(k,2)+...
                                G1(k,3)*G1(k,3))/2;
                            d_lj = G1(l,1)*cornersbz(j,1)+G1(l,2)*cornersbz(j,2)+...
                                G1(l,3)*cornersbz(j,3)-(G1(l,1)*G1(l,1)+G1(l,2)*G1(l,2)+...
                                G1(l,3)*G1(l,3))/2;
                            if (abs(d_ki)<delta) && (abs(d_li)<delta)...
                                    && (abs(d_kj)<delta)&& (abs(d_lj)<delta)
                                Ne = Ne+1;
                                edges(Ne,:) = [cornersbz(i,1),cornersbz(i,2),...
                                    cornersbz(i,3),cornersbz(j,1),cornersbz(j,2),...
                                    cornersbz(j,3)];
                                break
                            end
                        end
                    end
                end
            end

            fprintf('Edges:\n');
            for i = 1:size(edges,1)
                fprintf('(%.2f,%.2f,%.2f) to (%.2f,%.2f,%.2f)\n',edges(i,1),...
                    edges(i,2),edges(i,3),edges(i,4),edges(i,5),edges(i,6))
            end
            fprintf('Number of edges: %d\n',size(edges,1));

            for i = 1:size(edges,1)
                plot3(app.UIAxes_2,[edges(i,1),edges(i,4)],[edges(i,2),edges(i,5)],...
                    [edges(i,3),edges(i,6)],'k-','LineWidth',2.0);
            end

            view(app.UIAxes_2,3)
            axis(app.UIAxes_2,'equal')
            lighting(app.UIAxes_2,'phong');

            %             xlim(app.UIAxes_2,[-2.0 2.0])
            %             ylim(app.UIAxes_2,[-2.0 2.0])
            %             zlim(app.UIAxes_2,[-2.0 2.0])

            axis(app.UIAxes_2,'vis3d')

            camlight(app.UIAxes_2);
            daspect(app.UIAxes_2,[1,1,1]);

            axis(app.UIAxes_2,'off');
            axis(app.UIAxes_2,'equal');

            view(app.UIAxes_2,98.0797,9.1829);


        end

        function plot_struct(app)
            get_atom_info(app);

            quality = 11-app.QualitySlider.Value; % 1 means the best

            % define parameters

            data = app.posdata;

            trans_op = [0 0 0; 1 0 0; 0 1 0; 0 0 1; 1 1 0; 1 0 1; 0 1 1; 1 1 1];

            pos = cell(1,1);
            poslabel = cell(1,1);
            poscolor = cell(1,1);
            posradius = cell(1,1);
            posvisible = cell(1,1);

            %find relative atoms
            cnt_p = 0;
            for i = 1:size(data.atomcount,1)
                tmp_pos = zeros(1,3);
                tmp_color = cell(1,1);
                tmp_radius = zeros(1,3);
                tmp_label = cell(1,1);
                tmp_visible = zeros(1,1);
                cnt = 0;
%                 for m = 1:size(app.element_name,2)
%                     if contains(lower(data.symbols{i}),lower(app.element_name{m}))
%                         color_e = app.element_color(m,:);
%                         radius_e = app.element_radius(m);
%                         break;
%                     end
%                 end
                for j = 1:data.atomcount(i)
                    cnt_p = cnt_p + 1;
                    for k = 1:size(trans_op,1)
                        p = data.coords(cnt_p,:)+trans_op(k,:);
                        if p(1) <=1 && p(2) <=1 && p(3) <= 1
                            cnt = cnt + 1;
                            tmp_pos(cnt,:) = p*data.lattice;
                            tmp_color{cnt} = app.atom_info{cnt_p}{4};
                            tmp_radius(cnt) = app.atom_info{cnt_p}{3};
                            tmp_visible(cnt) = app.atom_info{cnt_p}{5};
                            tmp_label{cnt} = data.symbols{i};
                        end
                    end
                end
                pos{i} = tmp_pos;
                poscolor{i} = tmp_color;
                posradius{i} = tmp_radius;
                poslabel{i} = tmp_label;
                posvisible{i} = tmp_visible;
            end

            % set up color and radius
            radius = posradius;
            color_s = poscolor;

            for i = 1:size(pos,2)
                for j = 1:size(pos{i},1)
                    if posvisible{i}(j)
                        drawSphere(app.UIAxes,[pos{i}(j, :) radius{i}(j)], 'FaceColor', color_s{i}{j},'nPhi', 360/quality, 'nTheta', 180/quality);
                    end
                end
            end

            % plot bonds
            if (app.BondsCheckBox.Value)
                for i = 1:size(pos,2)
                    for j = i:size(pos,2)
                        plot_bond(app,pos{i},pos{j},2.7,'k',0.05)
                    end
                end
            end


            %plot box with the three main axes

            if (app.UnitcellCheckBox.Value)
                plot_box(app,app.posdata.lattice,'k',app.EditField_3.Value,app.DropDown.Value)
            end

            for i = 1:size(app.UITable.Data)
                if (app.UITable.Data{i,4})
                    lg = light(app.UIAxes);
                    lpos = split(app.UITable.Data{i,2});
                    lg.Position = [str2double(lpos(1)) str2double(lpos(2)) str2double(lpos(3))];
                    lcolor = app.UITable.Data{i,3};
                    lg.Color = lcolor;
                end
            end
            if (app.fiopen)
                view(app.UIAxes,app.EditField_1.Value,app.EditField_2.Value);
            else
                view(app.UIAxes,98.0797,9.1829);
            end
            axis(app.UIAxes,'off');
            axis(app.UIAxes,'equal');
            %             set(app.UIAxes,'render','opengl');
        end

        function plot_box(app,lat,color,linewidth,type)
            % get vertices of the box
            p1 = [0 0 0];
            p2 = lat(1,:);
            p3 = lat(2,:);
            p4 = lat(3,:);
            p5 = p2 + p4;
            p6 = p2 + p3;
            p7 = p3 + p4;
            p8 = p4 + p6;

            % plot edges
            drawEdge3d(app.UIAxes,[p1 p2], 'Color',color,'LineWidth',linewidth,'LineStyle',type);
            drawEdge3d(app.UIAxes,[p1 p3], 'Color',color,'LineWidth',linewidth,'LineStyle',type);

            drawEdge3d(app.UIAxes,[p2 p6], 'Color',color,'LineWidth',linewidth,'LineStyle',type);
            drawEdge3d(app.UIAxes,[p3 p6], 'Color',color,'LineWidth',linewidth,'LineStyle',type);

            drawEdge3d(app.UIAxes,[p4 p5], 'Color',color,'LineWidth',linewidth,'LineStyle',type);
            drawEdge3d(app.UIAxes,[p4 p7], 'Color',color,'LineWidth',linewidth,'LineStyle',type);

            drawEdge3d(app.UIAxes,[p5 p8], 'Color',color,'LineWidth',linewidth,'LineStyle',type);
            drawEdge3d(app.UIAxes,[p7 p8], 'Color',color,'LineWidth',linewidth,'LineStyle',type);

            drawEdge3d(app.UIAxes,[p1 p4], 'Color',color,'LineWidth',linewidth,'LineStyle',type);
            drawEdge3d(app.UIAxes,[p2 p5], 'Color',color,'LineWidth',linewidth,'LineStyle',type);
            drawEdge3d(app.UIAxes,[p3 p7], 'Color',color,'LineWidth',linewidth,'LineStyle',type);
            drawEdge3d(app.UIAxes,[p6 p8], 'Color',color,'LineWidth',linewidth,'LineStyle',type);

            close(gcf);
        end

        function clear_fig(app)
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            app.UIAxes.XColor = 'w';
            app.UIAxes.YColor = 'w';
            app.UIAxes.ZColor = 'w';
            app.UIAxes_2.XColor = 'w';
            app.UIAxes_2.YColor = 'w';
            app.UIAxes_2.ZColor = 'w';
            hold(app.UIAxes,'on');
            hold(app.UIAxes_2,'on');
        end

        function plot_bond(app,pos1,pos2,rcut,color,width)
            for i = 1:size(pos1,1)
                for j = 1:size(pos2,1)
                    d = norm(pos1(i,:)-pos2(j,:));
                    if d <= rcut && d > 0
                        drawCylinder(app.UIAxes,[pos1(i,:) pos2(j,:) width], 'FaceColor', color);
                    end
                end
            end
        end

        function add_light_default(app)
            app.UITable.Data = {1,'1 -1.3 1.5',rgb2hex([1 1 1]),true;2,'1 1.3 -1.2',rgb2hex([0.2 0.2 0.2]),true};
            s = uistyle('BackgroundColor',[1 1 1]);
            addStyle(app.UITable,s,'cell',[1,3]);
            s = uistyle('BackgroundColor',[0.2 0.2 0.2]);
            addStyle(app.UITable,s,'cell',[2,3]);
        end

        function result = cell_click(~, fig_handle)
            pause(0.5); % to allow the user time to add a second click
            if strcmpi(fig_handle.SelectionType, 'open')
                result = 1;
            else
                result = 0;
            end
        end
        
        function get_atom_info(app)
            if ~ app.fiopen
                app.UITable2.Data = {0,0,0,0,0};
                cnt = 0;
                for i = 1:size(app.posdata.symbols,1)
                    for m = 1:size(app.element_name,2)
                        if contains(lower(app.posdata.symbols{i}),lower(app.element_name{m}))
                            color_e = app.element_color(m,:);
                            radius_e = app.element_radius(m);
                            break;
                        end
                    end
                    for j = 1:app.posdata.atomcount(i)
                        cnt = cnt + 1;
                        app.atom_info{cnt} = {app.posdata.symbols{i},sprintf('%f %f %f',app.posdata.coords(j,:)),radius_e,rgb2hex(color_e),true};
                        app.UITable2.Data(cnt,:) = app.atom_info{cnt};
                        s = uistyle('BackgroundColor',color_e);
                        addStyle(app.UITable2,s,'cell',[cnt,4]);
                    end
                end
            else
                for i = 1:size(app.atom_info,2)
                    for j = 1:size(app.atom_info{i},2)
                        app.atom_info{i}{j} = app.UITable2.Data{i,j};
                    end 
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %           axis(app.UIAxes,'equal')
            % if you don't want the black lines for the axes, include:
            clear_fig(app);
            add_light_default(app);
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            YN = uiconfirm(app.CrystalPlotUIFigure,'Do you want to close the app?', 'Close request');
            if strcmpi(YN,'OK')
                delete(app)
            end
        end

        % Menu selected function: OpenstructureMenu
        function OpenstructureMenuSelected(app, event)
            [file,path] = uigetfile('*.*');
            if file ~= 0
                app.fiopen = false;
                app.pospath = strcat(path,file);
                app.posdata = import_poscar(app.pospath);
                % clear figure
                clear_fig(app);
                %
                print_poscar_info(app);
                plot_struct(app)
                plot_BZ(app);
            end
            app.fiopen = true;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.fiopen
                % clear figure
                clear_fig(app);
                %
                plot_struct(app)
                plot_BZ(app);
            end
        end

        % Menu selected function: SavestructureMenu
        function SavestructureMenuSelected(app, event)
            [file,path] = uiputfile('*.fig','Save Figure','struct');
            copyUIAxes(app.UIAxes);
            savefig(strcat(path,file));
            close(gcf);
        end

        % Menu selected function: SaveBZMenu
        function SaveBZMenuSelected(app, event)
            [file,path] = uiputfile('*.fig','Save Figure','BZ');
            copyUIAxes(app.UIAxes_2);
            savefig(strcat(path,file));
            close(gcf);
        end

        % Window button motion function: CrystalPlotUIFigure
        function CrystalPlotUIFigureWindowButtonMotion(app, event)
            fview = get(app.UIAxes,'view');
            app.EditField_1.Value = fview(1);
            app.EditField_2.Value = fview(2);
        end

        % Value changed function: EditField_1
        function EditField_1ValueChanged(app, event)
            value = app.EditField_1.Value;
            value2 = app.EditField_2.Value;
            view(app.UIAxes,value,value2)
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_1.Value;
            value2 = app.EditField_2.Value;
            view(app.UIAxes,value,value2)
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.UITableLighting_cellid = event.Indices;
            if cell_click(app,app.CrystalPlotUIFigure) == 0
                if ~isempty(app.UITableLighting_cellid)
                    if app.UITableLighting_cellid(1,2) == 3
                        c = uisetcolor([1 0 0],'Select a color');
                        if c ~= 0
                            s = uistyle('BackgroundColor',c);
                            addStyle(app.UITable,s,'cell',[app.UITableLighting_cellid(1,1),app.UITableLighting_cellid(1,2)])
                            app.UITable.Data{app.UITableLighting_cellid(1,1),app.UITableLighting_cellid(1,2)} = rgb2hex([c(1),c(2),c(3)]);
                        end
                    end
                end
            end
        end

        % Menu selected function: AddMenu
        function AddMenuSelected(app, event)
            try
                app.UITable.Data(size(app.UITable.Data,1)+1,:) = {size(app.UITable.Data,1)+1,'1 0 0',rgb2hex([1 1 1]),true};
            catch

            end
        end

        % Menu selected function: RemoveMenu
        function RemoveMenuSelected(app, event)
            try
                app.UITable.Data(app.UITableLighting_cellid(1,1),:) = [];
                for i = 1:size(app.UITable.Data,1)
                    app.UITable.Data{i,1} = i;
                end
            catch
            end
        end

        % Cell selection callback: UITable2
        function UITable2CellSelection(app, event)
            app.UITableAtom_cellid = event.Indices;
            if cell_click(app,app.CrystalPlotUIFigure) == 0
                if ~isempty(app.UITableAtom_cellid)
                    if app.UITableAtom_cellid(1,2) == 4
                        c = uisetcolor([1 0 0],'Select a color');
                        if c ~= 0
                            s = uistyle('BackgroundColor',c);
                            addStyle(app.UITable2,s,'cell',[app.UITableAtom_cellid(1,1),app.UITableAtom_cellid(1,2)])
                            app.UITable2.Data{app.UITableAtom_cellid(1,1),app.UITableAtom_cellid(1,2)} = rgb2hex([c(1),c(2),c(3)]);
                        end
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CrystalPlotUIFigure = uifigure('Visible', 'off');
            app.CrystalPlotUIFigure.Name = 'CrystalPlot';
            app.CrystalPlotUIFigure.Icon = 'crystal.png';
            app.CrystalPlotUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @CrystalPlotUIFigureWindowButtonMotion, true);
            app.FileMenu = uimenu(app.CrystalPlotUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenstructureMenu = uimenu(app.FileMenu);
            app.OpenstructureMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenstructureMenuSelected, true);
            app.OpenstructureMenu.Separator = 'on';
            app.OpenstructureMenu.Text = 'Open structure';
            app.SavestructureMenu = uimenu(app.FileMenu);
            app.SavestructureMenu.MenuSelectedFcn = createCallbackFcn(app, @SavestructureMenuSelected, true);
            app.SavestructureMenu.Separator = 'on';
            app.SavestructureMenu.Text = 'Save structure';
            app.SaveBZMenu = uimenu(app.FileMenu);
            app.SaveBZMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveBZMenuSelected, true);
            app.SaveBZMenu.Text = 'Save BZ';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.EditMenu = uimenu(app.CrystalPlotUIFigure);
            app.EditMenu.Text = 'Edit';
            app.ViewMenu = uimenu(app.CrystalPlotUIFigure);
            app.ViewMenu.Text = 'View';
            app.ObjectsMenu = uimenu(app.CrystalPlotUIFigure);
            app.ObjectsMenu.Text = 'Objects';
            app.UtilitiesMenu = uimenu(app.CrystalPlotUIFigure);
            app.UtilitiesMenu.Text = 'Utilities';
            app.HelpMenu = uimenu(app.CrystalPlotUIFigure);
            app.HelpMenu.Text = 'Help';
            app.Toolbar = uitoolbar(app.CrystalPlotUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.GridLayout = uigridlayout(app.CrystalPlotUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.StructureTab = uitab(app.TabGroup);
            app.StructureTab.Title = 'Structure';
            app.GridLayout3 = uigridlayout(app.StructureTab);
            app.UIAxes = uiaxes(app.GridLayout3);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.BrillouinZoneTab = uitab(app.TabGroup);
            app.BrillouinZoneTab.Title = 'Brillouin Zone';
            app.GridLayout7 = uigridlayout(app.BrillouinZoneTab);
            app.UIAxes_2 = uiaxes(app.GridLayout7);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ZTick = [];
            app.TabGroup2 = uitabgroup(app.GridLayout2);
            app.OutputTab = uitab(app.TabGroup2);
            app.OutputTab.Title = 'Output';
            app.GridLayout4 = uigridlayout(app.OutputTab);
            app.TextArea_output = uitextarea(app.GridLayout4);
            app.TextArea_output.Editable = 'off';
            app.SummaryTab = uitab(app.TabGroup2);
            app.SummaryTab.Title = 'Summary';
            app.GridLayout5 = uigridlayout(app.SummaryTab);
            app.TextArea = uitextarea(app.GridLayout5);
            app.TextArea.Editable = 'off';
            app.CommentTab = uitab(app.TabGroup2);
            app.CommentTab.Title = 'Comment';
            app.GridLayout6 = uigridlayout(app.CommentTab);
            app.TextArea_3 = uitextarea(app.GridLayout6);
            app.TextArea_3.Editable = 'off';
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Panel';
            app.GridLayout9 = uigridlayout(app.Panel);
            app.Button = uibutton(app.GridLayout9, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'chart.png';
            app.Button.Tooltip = {'Plot'};
            app.Button.Text = '';
            app.TabGroup3 = uitabgroup(app.GridLayout);
            app.ToolsTab = uitab(app.TabGroup3);
            app.ToolsTab.Title = 'Tools';
            app.GridLayout10 = uigridlayout(app.ToolsTab);
            app.AppearancePanel = uipanel(app.GridLayout10);
            app.AppearancePanel.Title = 'Appearance';
            app.GridLayout11 = uigridlayout(app.AppearancePanel);
            app.QualitySlider = uislider(app.GridLayout11);
            app.QualitySlider.Limits = [0 10];
            app.QualitySlider.MajorTicks = [0 5 10];
            app.QualitySlider.MinorTicks = [];
            app.QualitySlider.Value = 10;
            app.QualitySliderLabel = uilabel(app.GridLayout11);
            app.QualitySliderLabel.Text = 'Quality';
            app.GridLayout12 = uigridlayout(app.GridLayout11);
            app.UnitcellCheckBox = uicheckbox(app.GridLayout12);
            app.UnitcellCheckBox.Text = 'Unit cell';
            app.UnitcellCheckBox.Value = true;
            app.BondsCheckBox = uicheckbox(app.GridLayout12);
            app.BondsCheckBox.Text = 'Bonds';
            app.EditField_3 = uieditfield(app.GridLayout12, 'numeric');
            app.EditField_3.Limits = [0.01 Inf];
            app.EditField_3.Value = 1.5;
            app.DropDown = uidropdown(app.GridLayout12);
            app.DropDown.Items = {'-', '--', '-.', ':'};
            app.DropDown.Value = '-';
            app.OptionsLabel = uilabel(app.GridLayout11);
            app.OptionsLabel.Text = 'Options';
            app.ViewLabel = uilabel(app.GridLayout11);
            app.ViewLabel.Text = 'View';
            app.GridLayout14 = uigridlayout(app.GridLayout11);
            app.EditField_2 = uieditfield(app.GridLayout14, 'numeric');
            app.EditField_2.RoundFractionalValues = 'on';
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_1 = uieditfield(app.GridLayout14, 'numeric');
            app.EditField_1.RoundFractionalValues = 'on';
            app.EditField_1.ValueChangedFcn = createCallbackFcn(app, @EditField_1ValueChanged, true);
            app.LightingPanel = uipanel(app.GridLayout10);
            app.LightingPanel.Title = 'Lighting';
            app.GridLayout13 = uigridlayout(app.LightingPanel);
            app.UITable = uitable(app.GridLayout13);
            app.UITable.ColumnName = {'ID'; 'Coordinate'; 'Color'; 'Use'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true false true];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.StyleTab_2 = uitab(app.TabGroup3);
            app.StyleTab_2.Title = 'Style';
            app.ObjectsTab = uitab(app.TabGroup3);
            app.ObjectsTab.Title = 'Objects';
            app.GridLayout15 = uigridlayout(app.ObjectsTab);
            app.UITable2 = uitable(app.GridLayout15);
            app.UITable2.ColumnName = {'Site'; 'Position'; 'r (Å)'; 'C'; 'V'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = [false false true false true];
            app.UITable2.CellSelectionCallback = createCallbackFcn(app, @UITable2CellSelection, true);
            app.ContextMenu = uicontextmenu(app.CrystalPlotUIFigure);
            app.AddMenu = uimenu(app.ContextMenu);
            app.AddMenu.MenuSelectedFcn = createCallbackFcn(app, @AddMenuSelected, true);
            app.AddMenu.Text = 'Add';
            app.RemoveMenu = uimenu(app.ContextMenu);
            app.RemoveMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveMenuSelected, true);
            app.RemoveMenu.Text = 'Remove';
            % Assign app.ContextMenu
            app.UITable.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.CrystalPlotUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CrystalPlot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CrystalPlotUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CrystalPlotUIFigure)
        end
    end
end